classdef Parameterize_input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        xp_parameterizeparameterssettingLabel  matlab.ui.control.Label
        OKButton                     matlab.ui.control.Button
        apEquationEditFieldLabel     matlab.ui.control.Label
        apEquationEditField          matlab.ui.control.EditField
        peakEquationEditFieldLabel   matlab.ui.control.Label
        peakEquationEditField        matlab.ui.control.EditField
        parameterize_salceEditFieldLabel  matlab.ui.control.Label
        parameterize_salceEditField  matlab.ui.control.EditField
    end

    
    properties (Access = public)
        app_parent % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent)
            app.app_parent = app_parent;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            apEquation = app.apEquationEditField.Value;
            peakEquation = app.peakEquationEditField.Value;
            scale = app.parameterize_salceEditField.Value;
            
            app.app_parent.XiPi = xp_parameterize(app.app_parent.XiPi,'apEquation',apEquation,'peakEquation',peakEquation,'scale',scale);
            app.app_parent.passLamp_4.Color = "g";
            app.app_parent.parameterizescaleTextArea.Value = app.app_parent.XiPi.parameterizeSalce;
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.xp_parameterizeparameterssettingLabel = uilabel(app.UIFigure);
            app.xp_parameterizeparameterssettingLabel.Text = '  xp_parameterize( ) - parameters setting';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.apEquationEditFieldLabel = uilabel(app.UIFigure);
            app.apEquationEditFieldLabel.Text = 'apEquation';
            app.apEquationEditField = uieditfield(app.UIFigure, 'text');
            app.apEquationEditField.Value = 'b - log10(x^a)';
            app.peakEquationEditFieldLabel = uilabel(app.UIFigure);
            app.peakEquationEditFieldLabel.Text = 'peakEquation';
            app.peakEquationEditField = uieditfield(app.UIFigure, 'text');
            app.peakEquationEditField.Value = 'a1*exp(-((x-b1)/c1)^2)';
            app.parameterize_salceEditFieldLabel = uilabel(app.UIFigure);
            app.parameterize_salceEditFieldLabel.Text = 'parameterize_salce';
            app.parameterize_salceEditField = uieditfield(app.UIFigure, 'text');
            app.parameterize_salceEditField.Value = 'logarithm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parameterize_input(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end